classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DefinicinvariablesTab         matlab.ui.container.Tab
        StopButton                    matlab.ui.control.Button
        IteGlo                        matlab.ui.control.NumericEditField
        IteracionesGlobalLabel        matlab.ui.control.Label
        IndGlo                        matlab.ui.control.NumericEditField
        IndividuosGlobalLabel         matlab.ui.control.Label
        IteLat                        matlab.ui.control.NumericEditField
        IteracioneslateralLabel       matlab.ui.control.Label
        IndLat                        matlab.ui.control.NumericEditField
        IndividuoslateralLabel        matlab.ui.control.Label
        IteLon                        matlab.ui.control.NumericEditField
        IteracioneslongitudinalLabel  matlab.ui.control.Label
        IndLon                        matlab.ui.control.NumericEditField
        IndividuoslongitudinalLabel   matlab.ui.control.Label
        TimeStop                      matlab.ui.control.NumericEditField
        TiempoStopLabel               matlab.ui.control.Label
        AmplitudVelocidad             matlab.ui.control.TextArea
        AmplitudVelocidadLabel        matlab.ui.control.Label
        TiempoVelocidad               matlab.ui.control.TextArea
        TiempoVelocidadLabel          matlab.ui.control.Label
        AmplitudPitch                 matlab.ui.control.TextArea
        AmplitudPitchLabel            matlab.ui.control.Label
        TiempoPitch                   matlab.ui.control.TextArea
        TiempoPitchLabel              matlab.ui.control.Label
        AmplitudRoll                  matlab.ui.control.TextArea
        AmplitudRollLabel             matlab.ui.control.Label
        TiempoRoll                    matlab.ui.control.TextArea
        TiempoRollLabel               matlab.ui.control.Label
        SimularButton                 matlab.ui.control.Button
        DefinicindevariablesLabel     matlab.ui.control.Label
        ResultadoslongitudinalTab     matlab.ui.container.Tab
        ResultadosAjusteLongitudinalLabel  matlab.ui.control.Label
        UITable4                      matlab.ui.control.Table
        UITable3                      matlab.ui.control.Table
        UITable2                      matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ResultadoslateralTab          matlab.ui.container.Tab
        ResultadosAjusteLongitudinalLabel_2  matlab.ui.control.Label
        UITable8                      matlab.ui.control.Table
        UITable7                      matlab.ui.control.Table
        UITable6                      matlab.ui.control.Table
        UITable5                      matlab.ui.control.Table
        UIAxes5_2                     matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes3_4                     matlab.ui.control.UIAxes
        UIAxes3_3                     matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        ResultadosparmetrosglobalTab  matlab.ui.container.Tab
        ResultadosparmetrosajusteglobalLabel  matlab.ui.control.Label
        UITable13_2                   matlab.ui.control.Table
        UITable12_2                   matlab.ui.control.Table
        UITable11_2                   matlab.ui.control.Table
        UITable10_2                   matlab.ui.control.Table
        UITable13                     matlab.ui.control.Table
        UITable10                     matlab.ui.control.Table
        UITable11                     matlab.ui.control.Table
        UITable12                     matlab.ui.control.Table
        ResultadosgrficosparmetrosglobalTab  matlab.ui.container.Tab
        ResultadosgrficosparmetrosajusteglobalLabel  matlab.ui.control.Label
        UIAxes16                      matlab.ui.control.UIAxes
        UIAxes15                      matlab.ui.control.UIAxes
        UIAxes14                      matlab.ui.control.UIAxes
        UIAxes13                      matlab.ui.control.UIAxes
        ResultadosactitudglobalTab    matlab.ui.container.Tab
        ResultadosactitudajusteglobalLabel  matlab.ui.control.Label
        UIAxes12_2                    matlab.ui.control.UIAxes
        UIAxes9_2                     matlab.ui.control.UIAxes
        UIAxes8_2                     matlab.ui.control.UIAxes
        UIAxes12                      matlab.ui.control.UIAxes
        UIAxes9                       matlab.ui.control.UIAxes
        UIAxes8                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gaDat)
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
           app.SimularButton.Text = 'Simulando ....';
            % Código auto-tuning 
% En este código se realiza una implementación de un sistema de auto-tuning
% para parámetros de control de un auto-piloto PX4.

timestop = app.TimeStop.Value;      % Define el tiempo final de la simulación
assignin("base","timestop",timestop);
%% Ajuste dinámica longitudinal 
    % Se desactivan las fuerzas laterales del modelo, un valor de 1 indica
    % que se activan y un valor de 0 que se desactivan.
    F_lon = 1;
    F_lat = 0;
    assignin("base","F_lat",F_lat);
assignin("base","F_lon",F_lon);

    % Define referencia a seguir
        nav = 0;        % Si nav = 0 se desactiva el control de posición, si nav = 0 se activa el control de posición
assignin("base","nav",nav);

    % Pitch ref: se establece como una entrada escalón
    tiempo_pitch = str2double(strsplit(app.TiempoPitch.Value{1}));
    amplitud_pitch = deg2rad(str2double(strsplit(app.AmplitudPitch.Value{1})));
pitch_sp =timeseries(amplitud_pitch,tiempo_pitch);
assignin("base","pitch_sp",pitch_sp);


    % Roll ref: se establece como un valor constante y 0 para el caso
    % longitudinal
tiempo_roll = [0];                                      % Tiempos de cambio
amplitud_roll= deg2rad([0]);                            % Amplitudes correspondientes de ángulo de alabeo en grados
roll_sp = timeseries(amplitud_roll,tiempo_roll);
assignin("base","roll_sp",roll_sp);

    % Velocidad ref: se establece como una entrada escalón
    tiempo_vel = str2double(strsplit(app.TiempoVelocidad.Value{1})); 
    amplitud_vel = str2double(strsplit(app.AmplitudVelocidad.Value{1})); 
velocidad_sp = timeseries(amplitud_vel,tiempo_vel);
assignin("base","velocidad_sp",velocidad_sp);

    % Throttle ref: se establece como un valor constante ya que este es
    % calculado por el control de posición.
throttle_sp = 0.6;
assignin("base","throttle_sp",throttle_sp);

% Sistema simulado 
    % Controles laterales: se establecen a un valor inicial de 0
    roll_P = 0;
    roll_I= 0;
    roll_D = 0;
    tc_roll = 1;
    roll_ff = 0;
    yaw_P = 0;
    yaw_I = 0;
    yaw_D = 0;
    yaw_ff = 0;

assignin("base","roll_P",roll_P);
assignin("base","roll_I",roll_I);
assignin("base","roll_D",roll_D);
assignin("base","tc_roll",tc_roll);
assignin("base","roll_ff",roll_ff);
assignin("base","yaw_P",yaw_P);
assignin("base","yaw_I",yaw_I);
assignin("base","yaw_D",yaw_D);
assignin("base","yaw_ff",yaw_ff);

    % Controles longitudinales 
    clear ("pitch_P","pitch_I","pitch_D","tc_pitch","pitch_ff");
    lb_pitch = [0 0 0 0.2 0];       % Límite inferior de los parámetros de control longitudinales
    ub_pitch = [10 10 10 1 10];     % Límite superior de los parámetros de control longitudinales 

    % Algoritmo genético
    InitSimulinkPlantVars      %Inicializa los parámetros del modelo de Simulink
    gaDat.Objfun = 'Error_Medio_Cuadratico_lon';       % Función objetivo dinámica longitudinal
    lb = lb_pitch;
    ub = ub_pitch;
    gaDat.FieldD = [lb; ub];
    gaDat.indini = [2.3433,9.9781,0.1149,0.5453,1.2786];
    gaDat.MAXGEN = app.IteLon.Value;   % Máximo número de iteraciones 
    gaDat.NIND = app.IndLon.Value;     % Número de individuos por población, su valor estandar entre 150-200
    gaDat = ga(gaDat);
    gaDat.xmin;  
    gaDat.fxmin;

 app.UITable.Data = gaDat.xmingen;
 app.UITable2.Data = gaDat.fxmingen;
 app.UITable3.Data = gaDat.xmin;
 app.UITable4.Data = gaDat.fxmin;
 plot(app.UIAxes,gaDat.xmingen)
 legend(app.UIAxes,{'pitch\_P','pitch\_I','pitch\_D','tc\_pitch','pitch\_ff'})
 plot(app.UIAxes_2,gaDat.fxmingen)

 pitch_P = gaDat.xmin(1);
pitch_I = gaDat.xmin(2);
pitch_D = gaDat.xmin(3);
tc_pitch = gaDat.xmin(4);
pitch_ff = gaDat.xmin(5);

assignin("base","pitch_P",pitch_P);
assignin("base","pitch_I",pitch_I);
assignin("base","pitch_D",pitch_D);
assignin("base","tc_pitch",tc_pitch);
assignin("base","pitch_ff",pitch_ff);

sim('FixedWing_Dynamic_Model_SIL_simulink');
Simulink_pitch_sp = load('simulacionSimulink_pitch_sp');
Simulink_RPY = load('simulacionSimulink_RPY');
Simulink_rates_sp = load('simulacionSimulink_ratesRPY_sp');
Simulink_rates = load('simulacionSimulink_ratesRPY');

plot(app.UIAxes2,Simulink_pitch_sp.ans.Time,Simulink_pitch_sp.ans.Data(:)*180/pi,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,2)*180/pi)
legend(app.UIAxes2,{'ref','est'})
plot(app.UIAxes2_2,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,2)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,2)*180/pi)
legend(app.UIAxes2_2,{'ref','est'})
%% Ajuste dinámica lateral
% Se activan las fuerzas laterales del modelo, un valor de 1 indica
% que se activan y un valor de 0 que se desactivan.
 F_lon = 1;
 F_lat = 1;
assignin("base","F_lat",F_lat);
assignin("base","F_lon",F_lon);

 % Define referencia a seguir

nav = 0; % Si nav = 0 se desactiva el control de posición, si nav = 0 se activa el control de posición
assignin("base","nav",nav);

    % Pitch ref: se establece como un valor constante y 0 para el caso
    % lateral
tiempo_pitch = [0];                                     % Tiempos de cambio de referencia 
amplitud_pitch = deg2rad([0]);                          % Amplitudes correspondientes de ángulo de cabeceo en grados 
pitch_sp =timeseries(amplitud_pitch,tiempo_pitch);
assignin("base","pitch_sp",pitch_sp);

    % Roll ref: se establece como una entrada escalón 
tiempo_roll = str2double(strsplit(app.TiempoRoll.Value{1}));                % Tiempos de cambio de referencia
amplitud_roll= deg2rad(str2double(strsplit(app.AmplitudRoll.Value{1})));       % Amplitudes correspondientes de ángulo de alabeo en grados
roll_sp = timeseries(amplitud_roll,tiempo_roll);
assignin("base","roll_sp",roll_sp);

    % Velocidad ref: se establece constante
tiempo_vel = [0];                                       % Tiempos de cambio de referencia 
amplitud_vel= [15];                                     % Amplitudes correspondientes de velocidad en m/s
velocidad_sp = timeseries(amplitud_vel,tiempo_vel);
assignin("base","velocidad_sp",velocidad_sp);

    % Throttle ref: se establece como un valor constante ya que este es
    % calculado por el control de posición.
throttle_sp = 0.6;
assignin("base","throttle_sp",throttle_sp);

% Sistema simulado 
    % Control longitudinal: valores de ajuste longitudinal 
    pitch_P = gaDat.xmin(1);
    pitch_I = gaDat.xmin(2);
    pitch_D = gaDat.xmin(3);
    pitch_tc = gaDat.xmin(4);
    pitch_ff = gaDat.xmin(5);

    assignin("base","pitch_P",pitch_P);
assignin("base","pitch_I",pitch_I);
assignin("base","pitch_D",pitch_D);
assignin("base","tc_pitch",tc_pitch);
assignin("base","pitch_ff",pitch_ff);

    % Controles laterales
clear("roll_P","roll_I","roll_D","tc_roll","roll_ff","yaw_P","yaw_I","yaw_D","yaw_ff");
    lb_roll = [0 0 0 0.2 0];            % Límite inferior de los parámetros de control laterales (alabeo)
    ub_roll = [10 10 10 1 10];          % Límite superior de los parámetros de control laterales (alabeo)
    lb_yaw = [0 0 0 0];                 % Límite inferior de los parámetros de control laterales (guiñada)
    ub_yaw = [10 10 10 10];             % Límite superior de los parámetros de control laterales (guiñada)

     % Algoritmo genético
    InitSimulinkPlantVars      %Inicializa los parámetros del modelo de Simulink
    gaDat2.Objfun = 'Error_Medio_Cuadratico_lat';       % Función objetivo dinámica lateral
    lb2 = [lb_roll,lb_yaw];
    ub2 = [ub_roll,ub_yaw];
    gaDat2.FieldD = [lb2; ub2];
    gaDat2.indini = [7.756,9.1684,0.1967,0.6349,3.145,9.7847,9.9334,0.268,1.4988];
    gaDat2.MAXGEN = app.IteLat.Value;    % Máximo número de iteraciones  
    gaDat2.NIND = app.IndLat.Value;     % Número de individuos por población, su valor estandar entre 150-200
    gaDat2 = ga(gaDat2);
    gaDat2.xmin;  
    gaDat2.fxmin;

    app.UITable5.Data = gaDat2.xmingen;
    app.UITable6.Data = gaDat2.fxmingen;
    app.UITable7.Data = gaDat2.xmin;
    app.UITable8.Data = gaDat2.fxmin;

    plot(app.UIAxes5,gaDat2.xmingen(:,1:5))
    legend(app.UIAxes5,{'roll\_P','roll\_I','roll\_D','tc\_roll','roll\_ff'})
    plot(app.UIAxes5_2,gaDat2.xmingen(:,6:9))
    legend(app.UIAxes5_2,{'yaw\_P','yaw\_I','yaw\_D','yaw\_ff'})
    plot(app.UIAxes6,gaDat2.fxmingen)

roll_P = gaDat2.xmin(1);
roll_I = gaDat2.xmin(2);
roll_D = gaDat2.xmin(3);
tc_roll = gaDat2.xmin(4);
roll_ff = gaDat2.xmin(5);
yaw_P = gaDat2.xmin(6);
yaw_I = gaDat2.xmin(7);
yaw_D = gaDat2.xmin(8);
yaw_ff = gaDat2.xmin(9);

assignin("base","roll_P",roll_P);
assignin("base","roll_I",roll_I);
assignin("base","roll_D",roll_D);
assignin("base","tc_roll",tc_roll);
assignin("base","roll_ff",roll_ff);
assignin("base","yaw_P",yaw_P);
assignin("base","yaw_I",yaw_I);
assignin("base","yaw_D",yaw_D);
assignin("base","yaw_ff",yaw_ff);

sim('FixedWing_Dynamic_Model_SIL_simulink');
Simulink_roll_sp = load('simulacionSimulink_roll_sp');
Simulink_RPY = load('simulacionSimulink_RPY');
Simulink_rates_sp = load('simulacionSimulink_ratesRPY_sp');
Simulink_rates = load('simulacionSimulink_ratesRPY');

plot(app.UIAxes3,Simulink_roll_sp.ans.Time,Simulink_roll_sp.ans.Data(:)*180/pi,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,1)*180/pi)
legend(app.UIAxes3,{'ref','est'})
plot(app.UIAxes3_2,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,3)*180/pi)
legend(app.UIAxes3_2,{'est'})
plot(app.UIAxes3_3,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,1)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,1)*180/pi)
legend(app.UIAxes3_3,{'ref','est'})
plot(app.UIAxes3_4,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,3)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,3)*180/pi)
legend(app.UIAxes3_4,{'ref','est'})

 %% Ajuste total del sistema
% Se activan las fuerzas laterales y longitudinales del modelo, un valor de 1 indica
% que se activan y un valor de 0 que se desactivan.
 F_lon = 1;
 F_lat = 1;
assignin("base","F_lat",F_lat);
assignin("base","F_lon",F_lon);

 % Define referencia a seguir

nav = 0; % Si nav = 0 se desactiva el control de posición, si nav = 0 se activa el control de posición

    % Pitch ref: se establece como una entrada escalón
tiempo_pitch = str2double(strsplit(app.TiempoPitch.Value{1}));               % Tiempos de cambio de referencia en segundos
amplitud_pitch = deg2rad(str2double(strsplit(app.AmplitudPitch.Value{1})));        % Amplitudes correspondientes de ángulo de cabeceo en grados 
pitch_sp =timeseries(amplitud_pitch,tiempo_pitch);
assignin("base","pitch_sp",pitch_sp);

    % Roll ref: se establece como una entrada escalón
tiempo_roll = str2double(strsplit(app.TiempoRoll.Value{1}));                % Tiempos de cambio de referencia en segundos
amplitud_roll= deg2rad(str2double(strsplit(app.AmplitudRoll.Value{1})));       % Amplitudes correspondientes de ángulo de alabeo en grados
roll_sp = timeseries(amplitud_roll,tiempo_roll);
assignin("base","roll_sp",roll_sp);

    % Velocidad ref: se establece como una entrada escalón
tiempo_vel = str2double(strsplit(app.TiempoVelocidad.Value{1}));           % Tiempos de cambio de referencia en segundos 
amplitud_vel= str2double(strsplit(app.AmplitudVelocidad.Value{1}));         % Amplitudes correspondientes de velocidad en m/s
velocidad_sp = timeseries(amplitud_vel,tiempo_vel);
assignin("base","velocidad_sp",velocidad_sp);

    % Throttle ref: se establece como un valor constante ya que este es
    % calculado por el control de posición.
throttle_sp = 0.6; 
assignin("base","throttle_sp",throttle_sp);

% Sistema simulado 
    % Control longitudinal
clear ("pitch_P","pitch_I","pitch_D","tc_pitch","pitch_ff");

    % Controles laterales
clear("roll_P","roll_I","roll_D","tc_roll","roll_ff","yaw_P","yaw_I","yaw_D","yaw_ff");

     % Algoritmo genético
    InitSimulinkPlantVars           %Inicializa los parámetros del modelo de Simulink
    gaDat3.Objfun = 'Error_Medio_Cuadratico_tot';       %Función objetivo dinámica longitudinal y lateral
    lb3 = [lb_roll,lb_pitch,lb_yaw];
    ub3 = [ub_roll,ub_pitch,ub_yaw];
    gaDat3.FieldD = [lb3; ub3];
    % Se toman como población inicial las soluciones obtenidas en los
    % ajustes anteriores
    %gaDat3.indini = [gaDat2.xmin(1),gaDat2.xmin(2),gaDat2.xmin(3),gaDat2.xmin(4),gaDat2.xmin(5),gaDat.xmin(1),gaDat.xmin(2),gaDat.xmin(3),gaDat.xmin(4),gaDat.xmin(5),gaDat2.xmin(6),gaDat2.xmin(7),gaDat2.xmin(8),gaDat2.xmin(9)];
    gaDat3.indini = [5.3848,3.0674,0.1809,0.6560,2.8481,2.2897,4.9061,0.0222,0.6643,1.5746,2.7887,7.2698,0.0052,1.8174];
    gaDat3.MAXGEN = app.IteGlo.Value;          % Número máximo de iteraciones 
    gaDat3.NIND = app.IndGlo.Value;           % Número de individuos por población, su valor estandar entre 150-200;
    gaDat3 = ga(gaDat3);
    gaDat3.xmin;  
    gaDat3.fxmin;

    app.UITable10.Data = gaDat3.xmingen(:,1:5);
    app.UITable11.Data = gaDat3.xmingen(:,6:10);
    app.UITable12.Data = gaDat3.xmingen(:,11:14);
    app.UITable13.Data = gaDat3.fxmingen;

    app.UITable10_2.Data = gaDat3.xmin(:,1:5);
    app.UITable11_2.Data = gaDat3.xmin(:,6:10);
    app.UITable12_2.Data = gaDat3.xmin(:,11:14);
    app.UITable13_2.Data = gaDat3.fxmin;

    plot(app.UIAxes13,gaDat3.xmingen(:,1:5))
    legend(app.UIAxes13,{'roll\_P','roll\_I','roll\_D','tc\_roll','roll\_ff'})
    plot(app.UIAxes14,gaDat3.xmingen(:,6:10))
    legend(app.UIAxes14,{'pitch\_P','pitch\_I','pitch\_D','tc\_pitch','pitch\_ff'})
    plot(app.UIAxes15,gaDat3.xmingen(:,11:14))
    legend(app.UIAxes15,{'yaw\_P','yaw\_I','yaw\_D','yaw\_ff'})
    plot(app.UIAxes16,gaDat3.fxmingen)


roll_P = gaDat3.xmin(1);
roll_I = gaDat3.xmin(2);
roll_D = gaDat3.xmin(3);
tc_roll = gaDat3.xmin(4);
roll_ff = gaDat3.xmin(5);
pitch_P = gaDat3.xmin(6);
pitch_I = gaDat3.xmin(7);
pitch_D = gaDat3.xmin(8);
tc_pitch = gaDat3.xmin(9);
pitch_ff = gaDat3.xmin(10);
yaw_P = gaDat3.xmin(11);
yaw_I = gaDat3.xmin(12);
yaw_D = gaDat3.xmin(13);
yaw_ff = gaDat3.xmin(14);

assignin("base","roll_P",roll_P);
assignin("base","roll_I",roll_I);
assignin("base","roll_D",roll_D);
assignin("base","tc_roll",tc_roll);
assignin("base","roll_ff",roll_ff);
assignin("base","pitch_P",pitch_P);
assignin("base","pitch_I",pitch_I);
assignin("base","pitch_D",pitch_D);
assignin("base","tc_pitch",tc_pitch);
assignin("base","pitch_ff",pitch_ff);
assignin("base","yaw_P",yaw_P);
assignin("base","yaw_I",yaw_I);
assignin("base","yaw_D",yaw_D);
assignin("base","yaw_ff",yaw_ff);

sim('FixedWing_Dynamic_Model_SIL_simulink');
Simulink_roll_sp = load('simulacionSimulink_roll_sp');
Simulink_pitch_sp = load('simulacionSimulink_pitch_sp');
Simulink_RPY = load('simulacionSimulink_RPY');
Simulink_rates_sp = load('simulacionSimulink_ratesRPY_sp');
Simulink_rates = load('simulacionSimulink_ratesRPY');

plot(app.UIAxes8,Simulink_roll_sp.ans.Time,Simulink_roll_sp.ans.Data(:)*180/pi,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,1)*180/pi)
legend(app.UIAxes8,{'ref','est'})
plot(app.UIAxes8_2,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,1)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,1)*180/pi)
legend(app.UIAxes8_2,{'ref','est'})
plot(app.UIAxes9,Simulink_pitch_sp.ans.Time,Simulink_pitch_sp.ans.Data(:)*180/pi,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,2)*180/pi)
legend(app.UIAxes9,{'ref','est'})
plot(app.UIAxes9_2,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,2)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,2)*180/pi)
legend(app.UIAxes9_2,{'ref','est'})
plot(app.UIAxes12,Simulink_RPY.ans.Time,Simulink_RPY.ans.Data(:,3)*180/pi)
legend(app.UIAxes12,{'est'})
plot(app.UIAxes12_2,Simulink_rates_sp.ans.Time,Simulink_rates_sp.ans.Data(:,3)*180/pi,Simulink_rates.ans.Time,Simulink_rates.ans.Data(:,3)*180/pi)
legend(app.UIAxes12_2,{'ref','est'})
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.SimularButton.Text = 'Simular';
            app.IsRunning = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DefinicinvariablesTab = uitab(app.TabGroup);
            app.DefinicinvariablesTab.Title = 'Definición variables';
            app.DefinicindevariablesLabel = uilabel(app.DefinicinvariablesTab);
            app.DefinicindevariablesLabel.Text = 'Definición de variables';
            app.SimularButton = uibutton(app.DefinicinvariablesTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.TiempoRollLabel = uilabel(app.DefinicinvariablesTab);
            app.TiempoRollLabel.Text = 'Tiempo Roll';
            app.TiempoRoll = uitextarea(app.DefinicinvariablesTab);
            app.AmplitudRollLabel = uilabel(app.DefinicinvariablesTab);
            app.AmplitudRollLabel.Text = 'Amplitud Roll';
            app.AmplitudRoll = uitextarea(app.DefinicinvariablesTab);
            app.TiempoPitchLabel = uilabel(app.DefinicinvariablesTab);
            app.TiempoPitchLabel.Text = 'Tiempo Pitch';
            app.TiempoPitch = uitextarea(app.DefinicinvariablesTab);
            app.AmplitudPitchLabel = uilabel(app.DefinicinvariablesTab);
            app.AmplitudPitchLabel.Text = 'Amplitud Pitch';
            app.AmplitudPitch = uitextarea(app.DefinicinvariablesTab);
            app.TiempoVelocidadLabel = uilabel(app.DefinicinvariablesTab);
            app.TiempoVelocidadLabel.Text = 'Tiempo Velocidad';
            app.TiempoVelocidad = uitextarea(app.DefinicinvariablesTab);
            app.AmplitudVelocidadLabel = uilabel(app.DefinicinvariablesTab);
            app.AmplitudVelocidadLabel.Text = 'Amplitud Velocidad';
            app.AmplitudVelocidad = uitextarea(app.DefinicinvariablesTab);
            app.TiempoStopLabel = uilabel(app.DefinicinvariablesTab);
            app.TiempoStopLabel.Text = 'Tiempo Stop';
            app.TimeStop = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IndividuoslongitudinalLabel = uilabel(app.DefinicinvariablesTab);
            app.IndividuoslongitudinalLabel.Text = 'Individuos longitudinal';
            app.IndLon = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IteracioneslongitudinalLabel = uilabel(app.DefinicinvariablesTab);
            app.IteracioneslongitudinalLabel.Text = 'Iteraciones longitudinal';
            app.IteLon = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IndividuoslateralLabel = uilabel(app.DefinicinvariablesTab);
            app.IndividuoslateralLabel.Text = 'Individuos lateral';
            app.IndLat = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IteracioneslateralLabel = uilabel(app.DefinicinvariablesTab);
            app.IteracioneslateralLabel.Text = 'Iteraciones lateral';
            app.IteLat = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IndividuosGlobalLabel = uilabel(app.DefinicinvariablesTab);
            app.IndividuosGlobalLabel.Text = 'Individuos Global';
            app.IndGlo = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.IteracionesGlobalLabel = uilabel(app.DefinicinvariablesTab);
            app.IteracionesGlobalLabel.Text = 'Iteraciones Global';
            app.IteGlo = uieditfield(app.DefinicinvariablesTab, 'numeric');
            app.StopButton = uibutton(app.DefinicinvariablesTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResultadoslongitudinalTab = uitab(app.TabGroup);
            app.ResultadoslongitudinalTab.Title = 'Resultados longitudinal';
            app.UIAxes = uiaxes(app.ResultadoslongitudinalTab);
            title(app.UIAxes, 'Parámetros de control')
            xlabel(app.UIAxes, 'Iteraciones')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ResultadoslongitudinalTab);
            title(app.UIAxes2, 'Ángulo cabeceo')
            xlabel(app.UIAxes2, 'Tiempo [s]')
            ylabel(app.UIAxes2, 'pitch [deg]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.ResultadoslongitudinalTab);
            title(app.UIAxes2_2, 'Velocidad Cabeceo')
            xlabel(app.UIAxes2_2, 'Tiempo [s]')
            ylabel(app.UIAxes2_2, 'Pitch rate [deg/s]')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_2 = uiaxes(app.ResultadoslongitudinalTab);
            title(app.UIAxes_2, 'Error')
            xlabel(app.UIAxes_2, 'Iteraciones')
            zlabel(app.UIAxes_2, 'Z')
            app.UITable = uitable(app.ResultadoslongitudinalTab);
            app.UITable.ColumnName = {'Pitch_P_ite'; 'Pitch_D_ite'; 'Pitch_I_ite'; 'tc_Pitch_ite'; 'Pitch_ff_ite'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.ResultadoslongitudinalTab);
            app.UITable2.ColumnName = {'Error_iteraciones'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.ResultadoslongitudinalTab);
            app.UITable3.ColumnName = {'Pitch_P'; 'Pitch_I'; 'Pitch_D'; 'tc_Pitch'; 'Pitch_ff'};
            app.UITable3.RowName = {};
            app.UITable4 = uitable(app.ResultadoslongitudinalTab);
            app.UITable4.ColumnName = {'Error'};
            app.UITable4.RowName = {};
            app.ResultadosAjusteLongitudinalLabel = uilabel(app.ResultadoslongitudinalTab);
            app.ResultadosAjusteLongitudinalLabel.Text = 'Resultados Ajuste Longitudinal';
            app.ResultadoslateralTab = uitab(app.TabGroup);
            app.ResultadoslateralTab.Title = 'Resultados lateral';
            app.UIAxes3 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes3, 'Ángulo alabeo')
            xlabel(app.UIAxes3, 'Tiempo [s]')
            ylabel(app.UIAxes3, 'roll [deg]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes3_2, 'Ángulo guiñada')
            xlabel(app.UIAxes3_2, 'Tiempo [s]')
            ylabel(app.UIAxes3_2, 'yaw [deg]')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes3_3, 'Velocidad alabeo')
            xlabel(app.UIAxes3_3, 'Tiempo [s]')
            ylabel(app.UIAxes3_3, 'roll rate [deg/s]')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes3_4, 'Velocidad guiñada')
            xlabel(app.UIAxes3_4, 'Tiempo [s]')
            ylabel(app.UIAxes3_4, 'yaw rate [deg/s]')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes5 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes5, 'Parámetros Control Roll')
            xlabel(app.UIAxes5, 'Iteraciones')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes6, 'Error')
            xlabel(app.UIAxes6, 'Iteraciones')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5_2 = uiaxes(app.ResultadoslateralTab);
            title(app.UIAxes5_2, 'Parámetros Control Yaw')
            xlabel(app.UIAxes5_2, 'Iteraciones')
            zlabel(app.UIAxes5_2, 'Z')
            app.UITable5 = uitable(app.ResultadoslateralTab);
            app.UITable5.ColumnName = {'Roll_P_ite'; 'Roll_I_ite'; 'Roll_D_ite'; 'tc_Roll_ite'; 'Roll_ff_ite'; 'Yaw_P_ite'; 'Yaw_I_ite'; 'Yaw_D_ite'; 'Yaw_ff_ite'};
            app.UITable5.RowName = {};
            app.UITable6 = uitable(app.ResultadoslateralTab);
            app.UITable6.ColumnName = {'Error_ite'};
            app.UITable6.RowName = {};
            app.UITable7 = uitable(app.ResultadoslateralTab);
            app.UITable7.ColumnName = {'Roll_P'; 'Roll_I'; 'Roll_D'; 'tc_Roll'; 'Roll_ff'; 'Yaw_P'; 'Yaw_I'; 'Yaw_D'; 'Yaw_ff'};
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.ResultadoslateralTab);
            app.UITable8.ColumnName = {'Error'};
            app.UITable8.RowName = {};
            app.ResultadosAjusteLongitudinalLabel_2 = uilabel(app.ResultadoslateralTab);
            app.ResultadosAjusteLongitudinalLabel_2.Text = 'Resultados Ajuste Longitudinal';
            app.ResultadosparmetrosglobalTab = uitab(app.TabGroup);
            app.ResultadosparmetrosglobalTab.Title = 'Resultados parámetros global ';
            app.UITable12 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable12.ColumnName = {'Yaw_P_ite'; 'Yaw_I_ite'; 'Yaw_D_ite'; 'Yaw_ff_ite'};
            app.UITable12.RowName = {};
            app.UITable11 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable11.ColumnName = {'Pitch_P_ite'; 'Pitch_I_ite'; 'Pitch_D_ite'; 'tc_Pitch_ite'; 'Pitch_ff_ite'};
            app.UITable11.RowName = {};
            app.UITable10 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable10.ColumnName = {'Roll_P_ite'; 'Roll_I_ite'; 'Roll_D_ite'; 'tc_Roll'; 'Roll_ff_ite'};
            app.UITable10.RowName = {};
            app.UITable13 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable13.ColumnName = {'Error_ite'};
            app.UITable13.RowName = {};
            app.UITable10_2 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable10_2.ColumnName = {'Roll_P'; 'Roll_I'; 'Roll_D'; 'tc_Roll'; 'Roll_ff'};
            app.UITable10_2.RowName = {};
            app.UITable11_2 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable11_2.ColumnName = {'Pitch_P'; 'Pitch_I'; 'Pitch_D'; 'tc_Pitch'; 'Pitch_ff'};
            app.UITable11_2.RowName = {};
            app.UITable12_2 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable12_2.ColumnName = {'Yaw_P'; 'Yaw_I'; 'Yaw_D'; 'Yaw_ff'};
            app.UITable12_2.RowName = {};
            app.UITable13_2 = uitable(app.ResultadosparmetrosglobalTab);
            app.UITable13_2.ColumnName = {'Error'};
            app.UITable13_2.RowName = {};
            app.ResultadosparmetrosajusteglobalLabel = uilabel(app.ResultadosparmetrosglobalTab);
            app.ResultadosparmetrosajusteglobalLabel.Text = 'Resultados parámetros ajuste global';
            app.ResultadosgrficosparmetrosglobalTab = uitab(app.TabGroup);
            app.ResultadosgrficosparmetrosglobalTab.Title = 'Resultados gráficos parámetros global';
            app.UIAxes13 = uiaxes(app.ResultadosgrficosparmetrosglobalTab);
            title(app.UIAxes13, 'Parámetros Control Roll ')
            xlabel(app.UIAxes13, 'Iteraciones')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes14 = uiaxes(app.ResultadosgrficosparmetrosglobalTab);
            title(app.UIAxes14, 'Parámetros Control Pitch')
            xlabel(app.UIAxes14, 'Iteraciones')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes15 = uiaxes(app.ResultadosgrficosparmetrosglobalTab);
            title(app.UIAxes15, 'Parámetros Control Yaw')
            xlabel(app.UIAxes15, 'Iteraciones')
            zlabel(app.UIAxes15, 'Z')
            app.UIAxes16 = uiaxes(app.ResultadosgrficosparmetrosglobalTab);
            title(app.UIAxes16, 'Error')
            xlabel(app.UIAxes16, 'Iteraciones')
            zlabel(app.UIAxes16, 'Z')
            app.ResultadosgrficosparmetrosajusteglobalLabel = uilabel(app.ResultadosgrficosparmetrosglobalTab);
            app.ResultadosgrficosparmetrosajusteglobalLabel.Text = 'Resultados gráficos parámetros ajuste global';
            app.ResultadosactitudglobalTab = uitab(app.TabGroup);
            app.ResultadosactitudglobalTab.Title = 'Resultados actitud global';
            app.UIAxes8 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes8, 'Ángulo alabeo')
            xlabel(app.UIAxes8, 'Tiempo [s]')
            ylabel(app.UIAxes8, 'roll [deg]')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes9, 'Ángulo cabeceo')
            xlabel(app.UIAxes9, 'Tiempo [s]')
            ylabel(app.UIAxes9, 'pitch [deg]')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes12 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes12, 'Ángulo guiñada')
            xlabel(app.UIAxes12, 'Tiempo [s]')
            ylabel(app.UIAxes12, 'yaw [deg]')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes8_2 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes8_2, 'Velocidad alabeo')
            xlabel(app.UIAxes8_2, 'Tiempo [s]')
            ylabel(app.UIAxes8_2, 'roll rate [deg/s]')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes9_2 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes9_2, 'Velocidad cabeceo')
            xlabel(app.UIAxes9_2, 'Tiempo [s]')
            ylabel(app.UIAxes9_2, 'pitch rate [deg/s]')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes12_2 = uiaxes(app.ResultadosactitudglobalTab);
            title(app.UIAxes12_2, 'Velocidad guiñada ')
            xlabel(app.UIAxes12_2, 'Tiempo [s]')
            ylabel(app.UIAxes12_2, 'yaw rate [deg/s]')
            zlabel(app.UIAxes12_2, 'Z')
            app.ResultadosactitudajusteglobalLabel = uilabel(app.ResultadosactitudglobalTab);
            app.ResultadosactitudajusteglobalLabel.Text = 'Resultados actitud ajuste global';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end